classdef App_Quadratic1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EraseButton                   matlab.ui.control.Button
        EndButton                     matlab.ui.control.Button
        StartComputationButton        matlab.ui.control.Button
        x2EditField                   matlab.ui.control.NumericEditField
        x2EditFieldLabel              matlab.ui.control.Label
        x1EditField                   matlab.ui.control.NumericEditField
        x1EditFieldLabel              matlab.ui.control.Label
        dEditField                    matlab.ui.control.NumericEditField
        dEditFieldLabel               matlab.ui.control.Label
        cEditField                    matlab.ui.control.NumericEditField
        cEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        QUADRATICEQUATIONax2bxcLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartComputationButton
        function StartComputationButtonPushed(app, event)
            % Read input values
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;

            % Calculate discriminant
            d = b^2 - 4*a*c;

            % Display discriminant
            app.dEditField.Value = d;

             % Calculate roots
            x1 = (-b + sqrt(d))/(2*a);
            x2 = (-b - sqrt(d))/(2*a);

            % Display roots
            app.x1EditField.Value = x1;
            app.x2EditField.Value = x2;

        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
             % Clear all input and output values

            app.aEditField.Value = 0;
            app.bEditField.Value = 0;
            app.cEditField.Value = 0;

            app.dEditField.Value = 0;

            app.x1EditField.Value = 0;
            app.x2EditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QUADRATICEQUATIONax2bxcLabel = uilabel(app.UIFigure);
            app.QUADRATICEQUATIONax2bxcLabel.Text = {'QUADRATIC EQUATION '; '       ax^2 + bx +c '};
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a = ';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b = ';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c = ';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd = ';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1 = ';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2 = ';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.StartComputationButton = uibutton(app.UIFigure, 'push');
            app.StartComputationButton.ButtonPushedFcn = createCallbackFcn(app, @StartComputationButtonPushed, true);
            app.StartComputationButton.Text = 'Start Computation';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.EraseButton = uibutton(app.UIFigure, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Quadratic1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end